classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        StartButton       matlab.ui.control.Button
        TpEditField       matlab.ui.control.NumericEditField
        TpEditFieldLabel  matlab.ui.control.Label
        TdEditField       matlab.ui.control.NumericEditField
        TdEditFieldLabel  matlab.ui.control.Label
        TiEditField       matlab.ui.control.NumericEditField
        TiEditFieldLabel  matlab.ui.control.Label
        kpEditField       matlab.ui.control.NumericEditField
        kpEditFieldLabel  matlab.ui.control.Label
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            kp= app.kpEditField.Value;
            Td= app.TdEditField.Value;
            Ti = app.TiEditField.Value;
            Tp = app.TpEditField.Value;
            N = 4500;
            w = ones(1, N);
            y = zeros(1, N);
            e = zeros(1, N);
            u = zeros(1, N);
            ISE = zeros(1, N);
            IAE = zeros(1, N);
            y(1) = 0;
            y(2) = 0;
            e(1) = 1;
            e(2) = 1;
            sumaUchybow = e(1) + e(2);
            ISE(1) = Tp*1;
            ISE(2) = Tp*1;
            IAE(1) = Tp*2;
            IAE(2) = Tp*2;
            
            for i=3:N
                e(i) = w(i) - y(i-1);
                sumaUchybow = sumaUchybow + e(i);
                %równanie (6)
                u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                %wyznaczone równanie rekursywne
                y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                %wskaźniki jakości
                ISE(i) = ISE(i-1) + Tp*e(i)^2;
                IAE(i) = IAE(i-1) + Tp*abs(e(i));
            end
            
            t = 0:0.01:44.99;
            plot(app.UIAxes, t, y);
            plot(app.UIAxes2, t, e);
            plot(app.UIAxes3, t, u);
            plot(app.UIAxes4, t, ISE, t, IAE);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sygnał wyjściowy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Uchyb')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Sygnał sterujący')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Wskaźniki jakości')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.kpEditFieldLabel = uilabel(app.UIFigure);
            app.kpEditFieldLabel.Text = 'kp';
            app.kpEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpEditField.Value = 1.6;
            app.TiEditFieldLabel = uilabel(app.UIFigure);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiEditField.Value = 6;
            app.TdEditFieldLabel = uilabel(app.UIFigure);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.UIFigure, 'numeric');
            app.TdEditField.Value = 0.08;
            app.TpEditFieldLabel = uilabel(app.UIFigure);
            app.TpEditFieldLabel.Text = 'Tp';
            app.TpEditField = uieditfield(app.UIFigure, 'numeric');
            app.TpEditField.Value = 0.01;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end